classdef LSS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoFArmControlUIFigure          matlab.ui.Figure
        ValidLamp                      matlab.ui.control.Lamp
        ValidLampLabel                 matlab.ui.control.Label
        CHECKButton                    matlab.ui.control.Button
        HOMEButton                     matlab.ui.control.Button
        SENDButton                     matlab.ui.control.Button
        LSS4DoFRoboticArmControlLabel  matlab.ui.control.Label
        PositionPanel                  matlab.ui.container.Panel
        SPDSlider                      matlab.ui.control.Slider
        SPDSliderLabel                 matlab.ui.control.Label
        YmmSlider                      matlab.ui.control.Slider
        YmmSliderLabel                 matlab.ui.control.Label
        ZmmSlider                      matlab.ui.control.Slider
        ZmmSliderLabel                 matlab.ui.control.Label
        XmmSlider                      matlab.ui.control.Slider
        XmmSliderLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            %             ard=serialport('COM5',9600,'Parity','none');
            %             pause(2);
            %             waitforHUE(ard,"R");
            %             flush(ard,"input");
            %             flush(ard,"output");
            disp("Connected to HUEBERT")

        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)

            if([0 1 0] == app.ValidLamp.Color)
                X = app.XmmSlider.Value;
                Y = app.YmmSlider.Value;
                Z = app.ZmmSlider.Value;
                S = round(app.SPDSlider.Value);
                steps = [xyz2step([X,Y,Z]), S];
                disp(steps)
                %             send2ard(ard,'v',steps);
                %             waitforHUE(ard,"E");
            else
                disp("Not Valid Position");
            end
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            %             global s;
            %             if app.COMDropDown.Value == "ON"
            %                 fprintf(s, sprintf('#%d%s', 254, 'H'));
            %             else
            %                 helpdlg({'COM port is OFF',...
            %                     'Please turn it ON'});
            %             end
        end

        % Button pushed function: CHECKButton
        function CHECKButtonPushed(app, event)
            
            X = app.XmmSlider.Value;
            Y = app.YmmSlider.Value;
            Z = app.ZmmSlider.Value;
            S = round(app.SPDSlider.Value);
            steps = [xyz2step([X,Y,Z]), S];
            disp(steps)
            if(isreal(steps(:)))
            
                app.ValidLamp.Color = [0 1 0];
            else
                app.ValidLamp.Color = [1 0 0];

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoFArmControlUIFigure = uifigure('Visible', 'off');
            app.DoFArmControlUIFigure.Name = '4 DoF Arm Control';
            app.PositionPanel = uipanel(app.DoFArmControlUIFigure);
            app.PositionPanel.Title = 'Position';
            app.XmmSliderLabel = uilabel(app.PositionPanel);
            app.XmmSliderLabel.Text = 'X (mm)';
            app.XmmSlider = uislider(app.PositionPanel);
            app.XmmSlider.Limits = [50 350];
            app.XmmSlider.Value = 50;
            app.ZmmSliderLabel = uilabel(app.PositionPanel);
            app.ZmmSliderLabel.Text = 'Z (mm)';
            app.ZmmSlider = uislider(app.PositionPanel);
            app.ZmmSlider.Limits = [-100 100];
            app.YmmSliderLabel = uilabel(app.PositionPanel);
            app.YmmSliderLabel.Text = 'Y (mm)';
            app.YmmSlider = uislider(app.PositionPanel);
            app.YmmSlider.Limits = [-100 100];
            app.SPDSliderLabel = uilabel(app.PositionPanel);
            app.SPDSliderLabel.Text = 'SPD ';
            app.SPDSlider = uislider(app.PositionPanel);
            app.SPDSlider.Limits = [1 20];
            app.SPDSlider.Value = 1;
            app.LSS4DoFRoboticArmControlLabel = uilabel(app.DoFArmControlUIFigure);
            app.LSS4DoFRoboticArmControlLabel.Text = 'LSS 4DoF Robotic Arm Control';
            app.SENDButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.HOMEButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.CHECKButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.CHECKButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKButtonPushed, true);
            app.CHECKButton.Text = 'CHECK';
            app.ValidLampLabel = uilabel(app.DoFArmControlUIFigure);
            app.ValidLampLabel.Text = 'Valid';
            app.ValidLamp = uilamp(app.DoFArmControlUIFigure);
            % Show the figure after all components are created
            app.DoFArmControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSS_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoFArmControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoFArmControlUIFigure)
        end
    end
end